classdef airfoilApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UploadButton               matlab.ui.control.Button
        SelectAirfoilListBox       matlab.ui.control.ListBox
        SelectAirfoilListBoxLabel  matlab.ui.control.Label
        PanelsEditField            matlab.ui.control.NumericEditField
        PanelsEditFieldLabel       matlab.ui.control.Label
        PanelsSlider               matlab.ui.control.Slider
        PanelsSliderLabel          matlab.ui.control.Label
        FileTextArea               matlab.ui.control.TextArea
        FileTextAreaLabel          matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        AirfoilViewTab             matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        PanelViewTab               matlab.ui.container.Tab
        CMEditField                matlab.ui.control.NumericEditField
        CMEditFieldLabel           matlab.ui.control.Label
        CLEditField                matlab.ui.control.NumericEditField
        CLEditFieldLabel           matlab.ui.control.Label
        alphaEditField             matlab.ui.control.NumericEditField
        AlphaLabel                 matlab.ui.control.Label
        AlphaSlider                matlab.ui.control.Slider
        AoAAxes                    matlab.ui.control.UIAxes
        CPAxes                     matlab.ui.control.UIAxes
        datFileDirectoryButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        File = '';
        Path = ''; % Description
        Airfoil
        CPFlag = 0; % Description
    end
    
    methods (Access = private)
        
        function [CP,CL,M0,VEL] = VP(app,alpha)
            airfoilObj = app.Airfoil;
            
            % Convert alpha to rad
            AL = deg2rad(alpha);
            
            % Number of panels
            M = length(airfoilObj.xz.Collocation.x);
            
            % Iterate over i'th panel and find influence of j'th panel
            for i = 1:M
                for j = 1:M
            
                    X2T = airfoilObj.xz.Endpoints.x(j+1)-airfoilObj.xz.Endpoints.x(j);
                    Z2T = airfoilObj.xz.Endpoints.z(j+1)-airfoilObj.xz.Endpoints.z(j);
                    XT = airfoilObj.xz.Collocation.x(i)-airfoilObj.xz.Endpoints.x(j);
                    ZT = airfoilObj.xz.Collocation.z(i)-airfoilObj.xz.Endpoints.z(j);
             
                    X2 = X2T*cos(airfoilObj.Phi(j))+Z2T*sin(airfoilObj.Phi(j));
                    Z2 = 0;
                    X = XT*cos(airfoilObj.Phi(j))+ZT*sin(airfoilObj.Phi(j));
                    Z = -XT*sin(airfoilObj.Phi(j))+ZT*cos(airfoilObj.Phi(j));
                    
                    % Store length of panel
                    if i == 1
                        DL(j) = X2;
                    end
                    
                    % Panel is influencing on itself
                    if i == j
                        up = 0.5;
                        wp = 0;
                    else
                        % If panel is not influencing on itself, calc up
                        % and wp
                        [up,wp] = VOR2DC(app,X,Z,X2,Z2);
                    end
                    
                
                    U = up*cos(-airfoilObj.Phi(j))+wp*sin(-airfoilObj.Phi(j));
                    W = -up*sin(-airfoilObj.Phi(j))+wp*cos(-airfoilObj.Phi(j));
                    
                    % LHS Matrices
                    A(i,j) = -U*sin(airfoilObj.Phi(i))+W*cos(airfoilObj.Phi(i));
                    B(i,j) = U*cos(airfoilObj.Phi(i))+W*sin(airfoilObj.Phi(i));
                end
                % RHS Matrix
                b(i) = cos(AL)*sin(airfoilObj.Phi(i))-sin(AL)*cos(airfoilObj.Phi(i));
                
            end
            
            % Apply Kutta B.C.
            b = b';
            A(floor(M/4),:) = 0;
            A(floor(M/4),M) = 1;
            A(floor(M/4),1) = 1;
            b(floor(M/4)) = 0;
            
            % Solve for Gamma
            G = A\b;
            
            % Calculate V and Cp
            CL = 0;
            M0 = 0;
            L = 0;
            Vinf = 1;
            for i = 2:M
                TEMP = 0;
                for j = 1:M
                    TEMP=TEMP+B(i,j)*G(j);
                end
                VEL(i)=TEMP+cos(AL)*cos(airfoilObj.Phi(i))+sin(AL)*sin(airfoilObj.Phi(i));
                
                % Sum CL 
                CL = CL+G(i)*airfoilObj.PanelSize(i);
                
                % Sum Moments
                M0 = M0 + G(i)*airfoilObj.PanelSize(i)*(.25-airfoilObj.xz.Collocation.x(i))*cos(AL);
                
                % Calc CP
%                 CP(i) = 1-VEL(i)^2;
%                 CP(i) = 1-(cos(AL)*cos(airfoilObj.Phi(i))-sin(AL)*sin(airfoilObj.Phi(i))+G(i)/2)^2;
                CP(i) = 1-((VEL(i)+VEL(i-1))/2/Vinf)^2;     
                
            end
            
            % Finish CL calc
            CL = 2*CL/Vinf;
            
            % Store CP value in class
            airfoilObj.CP = CP;
%             M0 = sum(CP.*(airfoilObj.xz.Collocation.x-.25).*airfoilObj.PanelSize*cos(airfoilObj.Phi(i)));
            M0 = 2*M0/Vinf;
  
        end
        
        function plotCP(app,CP,alpha)
            airfoilObj = app.Airfoil;

            
            % Plot the rotated airfoil by *alpha* radians
            airfoilObj.panel2xz(deg2rad(alpha));
            airfoilObj.plotPanelsApp(app.AoAAxes,'panelCoords','equal');
            
            % Determine vector of top panels
            botInd = 1;
            topInd = 1;
            for i = 1:length(CP)
                zCoord = airfoilObj.xz.Collocation.z(i);
                
                if sign(zCoord) == -1
                    botVec(botInd,1) = CP(i);
                    botVec(botInd,2) = airfoilObj.xz.Collocation.x(i);
                    botInd = botInd+1;
                else
                    topVec(topInd,1) = CP(i);
                    topVec(topInd,2) = airfoilObj.xz.Collocation.x(i);
                    topInd = topInd+1;
                end
                
            end
            
            % Determine vector of bottom panels
            
            % Plot CP values 
            % plot(airfoilObj.xz.Collocation.x,CP,'b-o','markersize',3,'markerfacecolor','b'),grid on, grid minor
            hold(app.CPAxes,'off')
            plot(app.CPAxes,botVec(:,2),botVec(:,1),'ro','markersize',3,'markerfacecolor','r')
            hold(app.CPAxes,'on')
            plot(app.CPAxes,topVec(:,2),topVec(:,1),'bo','markersize',3,'markerfacecolor','b')
            legend(app.CPAxes,'Bottom','Top','location','best')
            title(app.CPAxes,[airfoilObj.FoilName,' at ',num2str(alpha),'° AoA'])
            app.CPAxes.YDir = 'reverse';
            % axis equal
            
        end

        function [up,wp] = VOR2DC(app,X,Z,X2,Z2)
            % 
            % R1 = X^2+Z^2;
            % R2 = (X-X2)^2+Z^2;
            % TH1 = atan2(Z,X);
            % TH2 = atan2(Z-Z2,X-X2);
            
            R1 = sqrt(X^2+Z^2);
            R2 = sqrt((X-X2)^2+Z^2);
            TH1 = atan2(Z,X);
            TH2 = atan2(Z,X-X2);
            
            
            up = (1/(2*pi))*(TH2-TH1);
            wp = (1/(2*pi))*log(R2/R1);
            
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PanelsSlider
        function PanelsSliderValueChanged(app, event)
            value = app.PanelsSlider.Value;
            value = floor(value);
            app.PanelsEditField.Value = value;
            app.Airfoil.discretize(value);
            app.Airfoil.plotPanelsApp(app.UIAxes,'xy','equal')
           

            app.Airfoil.plotNormalsApp(app.UIAxes);
            if app.CPFlag == 1
                alpha = app.alphaEditField.Value;
                [CP,CL,M0,VEL] = VP(app,alpha);
                app.CLEditField.Value = round(CL,4);
                app.CMEditField.Value = round(M0,4);
                plotCP(app,CP,alpha)
            end

        end

        % Button pushed function: datFileDirectoryButton
        function datFileDirectoryButtonPushed(app, event)
            foilPath = './Data Files';
            direc = uigetdir(foilPath);
            fileList = dir([direc,'/*.dat']);
            app.FileTextArea.Value = direc;
            for i = 1:length(fileList)
                app.SelectAirfoilListBox.Items{i} = fileList(i).name;
            end
            app.Path = direc;


        end

        % Value changed function: FileTextArea
        function FileTextAreaValueChanged(app, event)
%             value = app.FileTextArea.Value;
%             app.TextObject.Value = fullfile(path, file);
           
        end

        % Value changing function: PanelsSlider
        function PanelsSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = floor(changingValue);
            app.PanelsEditField.Value = changingValue;
%                         value = app.PanelsSlider.Value;
            app.Airfoil.discretize(changingValue);
            app.Airfoil.plotPanelsApp(app.UIAxes,'xy','equal')
            
            if app.CPFlag == 1
                alpha = app.alphaEditField.Value;
                [CP,CL,M0,VEL] = VP(app,alpha);
                app.CLEditField.Value = round(CL,4);
                app.CMEditField.Value = round(M0,4);
                plotCP(app,CP,alpha)
            end
  
        end

        % Value changed function: PanelsEditField
        function PanelsEditFieldValueChanged(app, event)
            value = app.PanelsEditField.Value;
            app.PanelsEditField.Value = value;
            app.Airfoil.discretize(value);
            ref_length = 1;
            hold(app.UIAxes,'off')
            plot(app.UIAxes,app.Airfoil.xz.Coords(:,1)./ref_length,app.Airfoil.xz.Coords(:,2)./ref_length,'r-','linewidth',3)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Airfoil.xz.Endpoints.x./ref_length,app.Airfoil.xz.Endpoints.z./ref_length,'g-','linewidth',2)
            plot(app.UIAxes,app.Airfoil.xz.Collocation.x./ref_length,app.Airfoil.xz.Collocation.z./ref_length,'ko',...
                'markerfacecolor','k','markersize',2)

            title(app.UIAxes,app.Airfoil.FoilName)
      
               
            fill(app.UIAxes,app.Airfoil.xz.Endpoints.x./ref_length,app.Airfoil.xz.Endpoints.z./ref_length,'g')
            app.Airfoil.plotNormalsApp(app.UIAxes);

            if app.CPFlag == 1
                alpha = app.alphaEditField.Value;
                [CP,CL,M0,VEL] = VP(app,alpha);
                app.CLEditField.Value = round(CL,4);
                app.CMEditField.Value = round(M0,4);
                plotCP(app,CP,alpha)
            end


        end

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            alpha = app.AlphaSlider.Value;
            app.alphaEditField.Value = alpha;
            
            [CP,CL,M0,VEL] = VP(app,alpha);
            app.CLEditField.Value = CL;
            app.CMEditField.Value = M0;
            plotCP(app,CP,alpha)
            app.CPFlag = 1;
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            alpha = app.alphaEditField.Value;
            app.AlphaSlider.Value = alpha;
            [CP,CL,M0,VEL] = VP(app,alpha);
            app.CLEditField.Value = CL;
            app.CMEditField.Value = M0;
            plotCP(app,CP,alpha)
            app.CPFlag = 1;
        end

        % Value changing function: AlphaSlider
        function AlphaSliderValueChanging(app, event)
            alpha = event.Value;
            app.alphaEditField.Value = alpha;
            app.AlphaSlider.Value = alpha;
            [CP,CL,M0,VEL] = VP(app,alpha);
            app.CLEditField.Value = round(CL,4);
            app.CMEditField.Value = round(M0,4);
            plotCP(app,CP,alpha)
            app.CPFlag = 1;
            
        end

        % Value changed function: SelectAirfoilListBox
        function SelectAirfoilListBoxValueChanged(app, event)
            value = app.SelectAirfoilListBox.Value;
            
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.CPAxes) 
            cla(app.AoAAxes) 
            app.Airfoil=[];
            fileValue = app.SelectAirfoilListBox.Value;
            fullFile = [app.Path,'/',fileValue];
            opts = detectImportOptions(fullFile);
            xyAirfoilData = readmatrix(fullFile,'NumHeaderLines',1);
            
            ref_length = 1;
            app.Airfoil = airfoilClass(fileValue,xyAirfoilData,ref_length);
            
            plot(app.UIAxes,app.Airfoil.xz.Coords(:,1), app.Airfoil.xz.Coords(:,2))
           
            title(app.UIAxes, app.File);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.datFileDirectoryButton = uibutton(app.UIFigure, 'push');
            app.datFileDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @datFileDirectoryButtonPushed, true);
            app.datFileDirectoryButton.Text = '.dat File Directory';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AirfoilViewTab = uitab(app.TabGroup);
            app.AirfoilViewTab.Title = 'Airfoil View';
            app.UIAxes = uiaxes(app.AirfoilViewTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PanelViewTab = uitab(app.TabGroup);
            app.PanelViewTab.Title = 'Panel View';
            app.CPAxes = uiaxes(app.PanelViewTab);
            title(app.CPAxes, 'Title')
            xlabel(app.CPAxes, 'X')
            ylabel(app.CPAxes, 'Y')
            zlabel(app.CPAxes, 'Z')
            app.AoAAxes = uiaxes(app.PanelViewTab);
            title(app.AoAAxes, 'Title')
            xlabel(app.AoAAxes, 'X')
            ylabel(app.AoAAxes, 'Y')
            zlabel(app.AoAAxes, 'Z')
            app.AlphaSlider = uislider(app.PanelViewTab);
            app.AlphaSlider.Limits = [-15 15];
            app.AlphaSlider.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @AlphaSliderValueChanging, true);
            app.AlphaLabel = uilabel(app.PanelViewTab);
            app.AlphaLabel.Text = 'Alpha';
            app.alphaEditField = uieditfield(app.PanelViewTab, 'numeric');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.CLEditFieldLabel = uilabel(app.PanelViewTab);
            app.CLEditFieldLabel.Text = 'CL';
            app.CLEditField = uieditfield(app.PanelViewTab, 'numeric');
            app.CLEditField.Editable = 'off';
            app.CMEditFieldLabel = uilabel(app.PanelViewTab);
            app.CMEditFieldLabel.Text = 'CM';
            app.CMEditField = uieditfield(app.PanelViewTab, 'numeric');
            app.CMEditField.Editable = 'off';
            app.FileTextAreaLabel = uilabel(app.UIFigure);
            app.FileTextAreaLabel.Text = 'File';
            app.FileTextArea = uitextarea(app.UIFigure);
            app.FileTextArea.ValueChangedFcn = createCallbackFcn(app, @FileTextAreaValueChanged, true);
            app.PanelsSliderLabel = uilabel(app.UIFigure);
            app.PanelsSliderLabel.Text = 'Panels';
            app.PanelsSlider = uislider(app.UIFigure);
            app.PanelsSlider.Limits = [0 160];
            app.PanelsSlider.MajorTicks = [0 20 40 60 80 100 120 140 160];
            app.PanelsSlider.ValueChangedFcn = createCallbackFcn(app, @PanelsSliderValueChanged, true);
            app.PanelsSlider.ValueChangingFcn = createCallbackFcn(app, @PanelsSliderValueChanging, true);
            app.PanelsEditFieldLabel = uilabel(app.UIFigure);
            app.PanelsEditFieldLabel.Text = 'Panels';
            app.PanelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PanelsEditField.ValueChangedFcn = createCallbackFcn(app, @PanelsEditFieldValueChanged, true);
            app.SelectAirfoilListBoxLabel = uilabel(app.UIFigure);
            app.SelectAirfoilListBoxLabel.Text = 'Select Airfoil';
            app.SelectAirfoilListBox = uilistbox(app.UIFigure);
            app.SelectAirfoilListBox.Items = {'Item 1', '', ''};
            app.SelectAirfoilListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAirfoilListBoxValueChanged, true);
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = airfoilApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end